classdef VL_CSA_AI_DICOM_to_TIF_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        panRight             matlab.ui.container.Panel
        txtProcessOpts       matlab.ui.control.TextArea
        panModel             matlab.ui.container.Panel
        lblStep1             matlab.ui.control.Label
        btnModelApply        matlab.ui.control.Button
        ddModelApplyOpt      matlab.ui.control.DropDown
        panCompile           matlab.ui.container.Panel
        ddProcess            matlab.ui.control.DropDown
        btnHelpProcess       matlab.ui.control.StateButton
        btnCompile           matlab.ui.control.Button
        btnProcessStudy      matlab.ui.control.Button
        panROIOptions        matlab.ui.container.Panel
        btnUseModel          matlab.ui.control.StateButton
        btnEditModel         matlab.ui.control.StateButton
        ddType               matlab.ui.control.DropDown
        btnNone              matlab.ui.control.StateButton
        lblStep2             matlab.ui.control.Label
        btnAdjustROI         matlab.ui.control.Button
        btnDelete            matlab.ui.control.Button
        btnDrawROI           matlab.ui.control.StateButton
        btnProcess           matlab.ui.control.Button
        panEnh               matlab.ui.container.Panel
        chkViewModel         matlab.ui.control.CheckBox
        chkHide              matlab.ui.control.CheckBox
        lblFilters           matlab.ui.control.Label
        ContrastSliderLabel  matlab.ui.control.Label
        sldContrast          matlab.ui.control.Slider
        ddFilters            matlab.ui.control.DropDown
        chkViewOriginal      matlab.ui.control.CheckBox
        btnLoad              matlab.ui.control.Button
        chkAutoLoad          matlab.ui.control.CheckBox
        panLeft              matlab.ui.container.Panel
        btnConvert           matlab.ui.control.Button
        txtFeedback          matlab.ui.control.Label
        btnForward           matlab.ui.control.Button
        btnBack              matlab.ui.control.Button
        btnAccept            matlab.ui.control.StateButton
        btnHome              matlab.ui.control.Button
        btnZoom              matlab.ui.control.Button
        btnPan               matlab.ui.control.Button
        btnStudyFolder       matlab.ui.control.Button
        txtStudyFolder       matlab.ui.control.EditField
        lblImages            matlab.ui.control.Label
        ddImages             matlab.ui.control.DropDown
        lblSubjects          matlab.ui.control.Label
        ddSubs               matlab.ui.control.DropDown
        axes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        D 
        D_grayscale
        roi
        roi_subq 
        params
        % model_path_cpu = 'model.onnx';
        % model_path_gpu = 'model_gpu.onnx';
        model_path_cpu = 'model_cpu.mat';
        model_path_gpu = 'model_gpu.mat';
        net_cpu
        net_gpu
        target_points_all = 260;
        target_points_editable = 50; 
        min_pixel_to_detect = 5000; %20000
        subq_model_color = 'r';
        subq_user_color = 'g';
        muscle_model_color = 'y';
        muscle_user_color = 'm'
    end
    
    methods (Access = private)

        function addListenersForViewChange(app)
            addlistener(app.axes, 'XLim', 'PostSet', @(~,~) saveCurrentViewLimits(app));
            addlistener(app.axes, 'YLim', 'PostSet', @(~,~) saveCurrentViewLimits(app));
        end

        function allevents(app, src, evt)
            if src == app.roi
                paramSave(app, 'roi_drawn', 1)
                paramSave(app, 'roi_position_x', evt.CurrentPosition(:,1))
                paramSave(app, 'roi_position_y', evt.CurrentPosition(:,2))
            elseif src == app.roi_subq
                paramSave(app, 'roi_subq_drawn', 1)
                paramSave(app, 'roi_subq_position_x', evt.CurrentPosition(:,1))
                paramSave(app, 'roi_subq_position_y', evt.CurrentPosition(:,2))
            end
        end

        function apply_model(app, img_path)
            og_img = imread(img_path);
            [origHeight, origWidth, ~] = size(app.D);
            img_resized = imresize(og_img, [512 512]);
            img_gray = im2single(rgb2gray(img_resized));

            % Add channel dimension
            inputData = reshape(img_gray, [size(img_gray, 1), size(img_gray, 2), 1]);
            
            % Predict using the network
            output = predict(app.net_cpu, inputData);
            
            % Save outputs for each ROI
            output_muscle = output(:,:,2);
            output_subq = output(:,:,1);
            
            % Resize outputs
            output_muscle_resized = imresize(output_muscle, [origHeight, origWidth]);
            output_subq_resized = imresize(output_subq, [origHeight, origWidth]);
            
            % For Debugging 
            % Normalize masks to [0, 1]
            output_muscle_resized = imbinarize(output_muscle_resized); % Muscle mask
            output_subq_resized = imbinarize(output_subq_resized);     % Subq mask
            
            % overlay_img = imoverlay_transparent(og_img, ...
            %     {output_subq_resized, output_muscle_resized}, ...         % Masks
            %     {'r', 'y'}, ...                       % Colors
            %     {0.15, 0.15});                        % Transparencies
            % imshow(overlay_img);
            
            %% Create Polygons
            % Muscle
            pixelCount = sum(output_muscle_resized(:));
            muscle_boundaries = bwboundaries(output_muscle_resized);
            if isempty(muscle_boundaries) || (pixelCount < app.min_pixel_to_detect)
                app.txtFeedback.Text = 'No muscle detected in image. Draw manually if needed';
                pause(2)
                app.txtFeedback.Text = '';
            else
                polygonBoundary = muscle_boundaries{1};
                roi_x_full = polygonBoundary(:, 2);
                roi_y_full = polygonBoundary(:, 1);
                [roi_position_x, roi_position_y] = downsample_polygon(app,roi_x_full, roi_y_full, app.target_points_editable);
                [roi_x, roi_y] = downsample_polygon(app,roi_x_full, roi_y_full, app.target_points_all);

                % For Debugging
                % imshow(output_muscle_resized);
                % hold on;
                % % plot(roi_x_full, roi_y_full, 'r-', 'LineWidth', 1);
                % % plot(roi_position_x, roi_position_y, 'b-', 'LineWidth', 1);
                % plot(roi_x, roi_y, 'r-', 'LineWidth', 1);

                % Save
                paramSave(app, 'roi_position_x', []);
                paramSave(app, 'roi_position_y', []);
                paramSave(app, 'roi_x', []);
                paramSave(app, 'roi_y', []);
                paramSave(app, 'roi_drawn', 0);

                % Save initial prediction as well
                paramSave(app, 'model_roi_position_x', roi_position_x);
                paramSave(app, 'model_roi_position_y', roi_position_y);
                paramSave(app, 'model_roi_x', roi_x);
                paramSave(app, 'model_roi_y', roi_y);
                paramSave(app, 'model_roi_drawn', 1);
            end

            % subq 
            pixelCount = sum(output_subq_resized(:));
            subq_boundaries = bwboundaries(output_subq_resized);
            if isempty(subq_boundaries) || (pixelCount < app.min_pixel_to_detect)
                app.txtFeedback.Text = 'No subq detected in image. Draw manual if needed';
                pause(2)
                app.txtFeedback.Text = '';
            else
                polygonBoundary = subq_boundaries{1}; 
                roi_x_subq_full = polygonBoundary(:, 2); 
                roi_y_subq_full = polygonBoundary(:, 1); 
                [roi_subq_position_x, roi_subq_position_y] = downsample_polygon(app, roi_x_subq_full, roi_y_subq_full, app.target_points_editable);
                [roi_subq_x, roi_subq_y] = downsample_polygon(app,roi_x_subq_full, roi_y_subq_full, app.target_points_all);
                
                % % For Debugging
                % imshow(output_subq_resized); 
                % hold on;
                % % plot(roi_x_subq_full, roi_y_subq_full, 'r-', 'LineWidth', 1);
                % % plot(roi_subq_position_x, roi_subq_position_y, 'b-', 'LineWidth', 1);
                % plot(roi_subq_x, roi_subq_y, 'r-', 'LineWidth', 1);
                
                % Save
                paramSave(app, 'roi_subq_position_x', []);
                paramSave(app, 'roi_subq_position_y', []);
                paramSave(app, 'roi_subq_x', []);
                paramSave(app, 'roi_subq_y', []);
                paramSave(app, 'roi_subq_drawn', 0);
                
                % Save initial prediction as well
                paramSave(app, 'model_roi_subq_position_x', roi_subq_position_x);
                paramSave(app, 'model_roi_subq_position_y', roi_subq_position_y);
                paramSave(app, 'model_roi_subq_x', roi_subq_x);
                paramSave(app, 'model_roi_subq_y', roi_subq_y);
                paramSave(app, 'model_roi_subq_drawn', 1);
            end
            
            paramSave(app, 'model_applied', 1);     
            override_check_use_model(app)
           
            % Notes on positions vs. roi params: 
            % positions are less points so user can edit them more easily
            % with the adjust button. They are also used for listeners 
            % rois are the full roi's with more points
        end
        
        function clear_measures(app, clear_both_rois)
            if nargin < 2; clear_both_rois = 0; end

            if clear_both_rois || strcmp(app.ddType.Value, 'muscle') 
                app.roi = [];
                paramSave(app, 'roi_x', [])
                paramSave(app, 'roi_y', [])
            end

            if clear_both_rois || strcmp(app.ddType.Value, 'subq')
                app.roi_subq = [];
                paramSave(app, 'roi_subq_x', [])
                paramSave(app, 'roi_subq_y', [])
            end

            refreshAxes(app, app.axes, 1)
        end
        
        function interpolate_and_save(app, roi, prefix)
            interp_method = 'linear';
            num_interp_points = app.target_points_all;

            % Undo the rotation to save the ROI
            posx = round(roi.Position(:, 1));
            posy = round(roi.Position(:, 2));

            t = linspace(0, 1, length(posx));
            tInterp = linspace(0, 1, num_interp_points);
            xinterp = round(interp1(t, posx, tInterp, interp_method))';
            yinterp = round(interp1(t, posy, tInterp, interp_method))';

            % Close polygon
            xinterp = [xinterp; xinterp(1)];
            yinterp = [yinterp; yinterp(1)];

            % Save points and interpolated ROIs
            paramSave(app, [prefix '_position_x'], posx);
            paramSave(app, [prefix '_position_y'], posy);
            paramSave(app, [prefix '_x'], xinterp);
            paramSave(app, [prefix '_y'], yinterp);
            paramSave(app, [prefix '_drawn'], 1);
        end 

        function [subject_folder_path, subject] = getSubject(app)
            [~, subject] = fileparts(fullfile(app.txtStudyFolder.Value,app.ddSubs.Items(app.ddSubs.Value)));
            subject_folder_path = fullfile(app.txtStudyFolder.Value,subject);
            if iscell(subject); subject = cell2mat(subject); end
            if iscell(subject_folder_path); subject_folder_path = cell2mat(subject_folder_path); end
        end

        function value = paramGet(app, param_name, default, matfile_path)
            if (nargin < 3) || (isempty(default)), default = []; end
            if (nargin < 4) || (isempty(matfile_path))
                matfile_path = app.params.path;
            end

            f = matfile(matfile_path, "Writable", true);
            if (any(ismember(who(f), param_name)))
                value = f.(param_name);
            else
                f.(param_name) = default;
                value = default;
            end
        end

        function paramSave(app, param_name, value)
            f = matfile(app.params.path, "Writable", true);
            if (nargin < 3)
                if (isfield(app.params, param_name))
                    value = app.params.(param_name);
                else
                    value = [];
                end
            end
            if strcmp(value, 'remove')
                file_info = whos('-file', f.Properties.Source);
                vars_to_keep = setdiff({file_info.name}, param_name);
                for i = 1:length(vars_to_keep)
                    var_data = load(f.Properties.Source, vars_to_keep{i});
                    eval([vars_to_keep{i} ' = var_data.' vars_to_keep{i} ';']);
                end
                save(f.Properties.Source, vars_to_keep{:}, '-v7.3');
            else
                f.(param_name) = value;
                app.params.(param_name) = value;
            end
        end

        function refreshAxes(app, axesTarget, clear_rois)
            if ((nargin < 2) || isempty(axesTarget)), axesTarget = app.axes; end
            if (nargin < 3), clear_rois = 0; end

            cla(app.axes)

            % Edit contrast
            if (isempty(app.D))
                cla(axesTarget);
                return
            end

            I = app.D;
            contrastFactor = app.sldContrast.Value*.01 + 0.5;
            I = uint8(double(I) * contrastFactor);

            % Filter options
            switch app.ddFilters.Value
                case 'None'
                    % Do nothing (I = I)
                case 'Auto'
                    gs = 0.2989 * I(:, :, 1, :) + 0.5870 * I(:, :, 2, :) + 0.1140 * I(:, :, 3, :);
                    I = adapthisteq(gs,'NumTiles',[8 8],'ClipLimit',0.002);
                case 'BW'
                    gs = 0.2989 * I(:, :, 1, :) + 0.5870 * I(:, :, 2, :) + 0.1140 * I(:, :, 3, :);
                    I = butter_MW(gs,5,180,4);
                case 'Auto+BW'
                    gs = 0.2989 * I(:, :, 1, :) + 0.5870 * I(:, :, 2, :) + 0.1140 * I(:, :, 3, :);
                    gs = adapthisteq(gs,'NumTiles',[8 8],'ClipLimit',0.002);
                    I = butter_MW(gs,5,180,4);
            end
        
            imagesc(axesTarget, I);
            colormap(axesTarget,'bone')
            axis(axesTarget, 'equal')
            hold(axesTarget, 'on')
            
            % MUSCLE
            if ~(clear_rois) && ~(app.chkHide.Value)
                model_applied = paramGet(app, 'model_applied', 0);
                if (model_applied)
                    if (app.chkViewModel.Value)
                        roi_x_muscle = paramGet(app, 'model_roi_x', double.empty(0, 2));
                        roi_y_muscle = paramGet(app, 'model_roi_y', double.empty(0, 2));
                        if ~isempty(roi_x_muscle) && ~isempty(roi_y_muscle)
                            plot(axesTarget, roi_x_muscle, roi_y_muscle, 'Color', app.muscle_model_color, 'LineWidth', 1.5);
                        end
                    else
                        roi_type = paramGet(app, 'roi_type', double.empty(0,2));
                        if isempty(roi_type)
                            roi_x_muscle = paramGet(app, 'model_roi_x', double.empty(0, 2));
                            roi_y_muscle = paramGet(app, 'model_roi_y', double.empty(0, 2));
                            if ~isempty(roi_x_muscle) && ~isempty(roi_y_muscle)
                                plot(axesTarget, roi_x_muscle, roi_y_muscle, 'Color', app.muscle_model_color, 'LineWidth', 1.5);
                            end
                        else
                            switch roi_type
                                case 'model'
                                    roi_x_muscle = paramGet(app, 'model_roi_x', double.empty(0, 2));
                                    roi_y_muscle = paramGet(app, 'model_roi_y', double.empty(0, 2));
                                    if ~isempty(roi_x_muscle) && ~isempty(roi_y_muscle)
                                        plot(axesTarget, roi_x_muscle, roi_y_muscle, 'Color', app.muscle_model_color, 'LineWidth', 1.5);
                                    end
                                case 'user'
                                    roi_drawn = paramGet(app, 'roi_drawn', 0);
                                    if (roi_drawn)
                                        roi_x_muscle = paramGet(app, 'roi_x', double.empty(0, 2));
                                        roi_y_muscle = paramGet(app, 'roi_y', double.empty(0, 2));
                                        if ~isempty(roi_x_muscle) && ~isempty(roi_y_muscle)
                                            plot(axesTarget, roi_x_muscle, roi_y_muscle, 'Color', app.muscle_user_color, 'LineWidth', 1.5);
                                        end
                                    else
                                        if (model_applied)
                                            roi_x_muscle = paramGet(app, 'model_roi_x', double.empty(0, 2));
                                            roi_y_muscle = paramGet(app, 'model_roi_y', double.empty(0, 2));
                                            if ~isempty(roi_x_muscle) && ~isempty(roi_y_muscle)
                                                plot(axesTarget, roi_x_muscle, roi_y_muscle, 'Color', app.muscle_model_color, 'LineWidth', 1.5);
                                            end
                                        end
                                    end
                                case 'none'
                                    % do nothing
                            end
                        end
                    end
                end
            end

            % SUBQ
            if ~(clear_rois) && ~(app.chkHide.Value)
                model_applied = paramGet(app, 'model_applied', 0);
                if (model_applied)
                    if (app.chkViewModel.Value)
                        roi_subq_x_model_muscle = paramGet(app, 'model_roi_subq_x', double.empty(0, 2));
                        roi_subq_y_model_muscle = paramGet(app, 'model_roi_subq_y', double.empty(0, 2));
                        if ~isempty(roi_subq_x_model_muscle) && ~isempty(roi_subq_y_model_muscle)
                            plot(axesTarget, roi_subq_x_model_muscle, roi_subq_y_model_muscle, 'Color', app.subq_model_color, 'LineWidth', 1.5);
                        end
                    else
                        roi_type = paramGet(app, 'roi_subq_type', double.empty(0,2));
                        if isempty(roi_type)
                            roi_subq_x_model_muscle = paramGet(app, 'model_roi_subq_x', double.empty(0, 2));
                            roi_subq_y_model_muscle = paramGet(app, 'model_roi_subq_y', double.empty(0, 2));
                            if ~isempty(roi_subq_x_model_muscle) && ~isempty(roi_subq_y_model_muscle)
                                plot(axesTarget, roi_subq_x_model_muscle, roi_subq_y_model_muscle, 'Color', app.subq_model_color, 'LineWidth', 1.5);
                            end
                        else
                            switch roi_type
                                case 'model'
                                    roi_subq_x_model_muscle = paramGet(app, 'model_roi_subq_x', double.empty(0, 2));
                                    roi_subq_y_model_muscle = paramGet(app, 'model_roi_subq_y', double.empty(0, 2));
                                    if ~isempty(roi_subq_x_model_muscle) && ~isempty(roi_subq_y_model_muscle)
                                        plot(axesTarget, roi_subq_x_model_muscle, roi_subq_y_model_muscle, 'Color', app.subq_model_color, 'LineWidth', 1.5);
                                    end
                                case 'user'
                                    roi_drawn = paramGet(app, 'roi_subq_drawn', 0);
                                    model_roi_drawn = paramGet(app, 'model_roi_subq_drawn', 0);
                                    if (roi_drawn)
                                        roi_x_subq = paramGet(app, 'roi_subq_x', double.empty(0, 2));
                                        roi_y_subq = paramGet(app, 'roi_subq_y', double.empty(0, 2));
                                        if ~isempty(roi_x_subq) && ~isempty(roi_y_subq)
                                            plot(axesTarget, roi_x_subq, roi_y_subq, 'Color', 'g', 'LineWidth', 1.5);
                                        end
                                    else
                                        if (model_roi_drawn)
                                            roi_subq_x_model_muscle = paramGet(app, 'model_roi_subq_x', double.empty(0, 2));
                                            roi_subq_y_model_muscle = paramGet(app, 'model_roi_subq_y', double.empty(0, 2));
                                            if ~isempty(roi_subq_x_model_muscle) && ~isempty(roi_subq_y_model_muscle)
                                                plot(axesTarget, roi_subq_x_model_muscle, roi_subq_y_model_muscle, 'Color', app.subq_model_color, 'LineWidth', 1.5);
                                            end
                                        end
                                    end
                                case 'none'
                                    % Do Nothing
                            end
                        end
                    end
                end
            end
           
            % visibility
            app.btnPan.Visible = 1;
            app.btnZoom.Visible = 1;
            app.btnHome.Visible = 1;
            app.btnBack.Visible = 1;
            app.btnForward.Visible = 1;
            % app.panModel.Visible = 1;
            % app.panCompile.Visible = 1;
            % app.panROIOptions.Visible = 1;
            % app.panEnh.Visible = 1;
            test_viewing_options(app)
        end

        function tracing_state(app, state, check_exception)
            if nargin > 2
                if (check_exception)
                    status = 'on';
                else
                    status = 'off';
                end
                app.panLeft.Enable = status;
                app.panRight.Enable = state;
                app.btnStudyFolder.Enable = state;
                app.txtStudyFolder.Enable = state;
                app.lblSubjects.Enable = state;
                app.ddSubs.Enable = state;
                app.lblImages.Enable = state;
                app.ddImages.Enable = state;
                app.btnBack.Visible = state;
                app.btnForward.Visible = state;
            else
                app.panLeft.Enable = state;
                app.panRight.Enable = state;
            end

            drawnow
        end


        function update_image_list(app, override)
            if nargin < 2; override = 0; end

            % Populate images
            [subject_folder, ~] = getSubject(app);
            all_files = dir2(subject_folder, '/s');

            image_paths = all_files(contains(all_files, '.tif'));
            if isempty(image_paths)
                % images may have no extension so
                [~, ~, ext] = cellfun(@fileparts, all_files, 'UniformOutput', false);
                image_paths = all_files(cellfun(@isempty, ext));
            end
                
            image_cells = cellfun(@(path) strsplit(path, filesep), image_paths, 'UniformOutput', false);
            images = cellfun(@(parts) parts{end}, image_cells, 'UniformOutput', false);
            num_images = length(images);

            % Check if no images
            if (num_images < 1)
                app.ddImages.Items = {};
                app.ddImages.ItemsData = [];
                errordlg(['No files found in "' subject_folder '".'], 'No file');
                tracing_state(app, 'on')
                return
            end

            % Get list of images
            for k = 1:num_images
                if endsWith(image_paths{k}, '.tif')
                    mat_file = strrep(image_paths{k}, '.tif', '.mat');
                else
                    mat_file = strcat(image_paths{k}, '.mat');
                end

                if exist(mat_file, 'file')
                    prev_drawn_rois = paramGet(app, 'roi_type', double.empty(0,2), mat_file);
                    prev_drawn_rois_subq = paramGet(app, 'roi_subq_type', double.empty(0,2), mat_file);
                    % if roi_type is user, make sure roi is drawn
                    % otherwise, save as empty 
                    if ~(isempty(prev_drawn_rois))
                        if strcmp(prev_drawn_rois, 'user')
                            % check if roi exists 
                            roi_drawn = paramGet(app, 'roi_drawn', double.empty(0,2), mat_file);
                            if isempty(roi_drawn) || ~(roi_drawn)
                                prev_drawn_rois = [];
                            end
                        end
                    end
                    
                    if ~(isempty(prev_drawn_rois_subq))
                        if strcmp(prev_drawn_rois_subq, 'user')
                            % check if roi exists 
                            roi_subq_drawn = paramGet(app, 'roi_subq_drawn', double.empty(0,2), mat_file);
                            if isempty(roi_subq_drawn) || ~(roi_subq_drawn)
                                prev_drawn_rois_subq = [];
                            end
                        end
                    end


                    % continue
                    if (isempty(prev_drawn_rois) && ~isempty(prev_drawn_rois_subq)) || (~isempty(prev_drawn_rois) && isempty(prev_drawn_rois_subq))
                        images{k} = strcat(images{k}, ' ', char(hex2dec('2713'))); %#ok<*AGROW>
                    elseif ~isempty(prev_drawn_rois) && ~isempty(prev_drawn_rois_subq)
                        images{k} = strcat(images{k}, ' ', char(hex2dec('2713')), char(hex2dec('2713'))); %#ok<*AGROW>
                    else
                        images{k} = images{k};
                    end
                else
                    images{k} = images{k};
                end
            end

            % Populate dropdown
            app.ddImages.Items = images;
            app.ddImages.ItemsData = 1:num_images;

            if (override)
                app.ddImages.Value = 1;
            end
        end
        
        function [downsampled_x, downsampled_y] = downsample_polygon(~, x, y, target_points)
            % Downsample a polygon to a target number of points
            % Inputs:
            % x, y: Coordinates of the original polygon (arrays of the same length)
            % target_points: Desired number of points in the downsampled polygon
            
            % Get total number of points in the original polygon
            total_points = length(x);
            
            % Determine the step size for downsampling
            step_size = max(1, round(total_points / target_points));
            
            % Downsample by selecting every step_size-th point
            downsampled_x = x(1:step_size:end);
            downsampled_y = y(1:step_size:end);
            
            % Ensure the polygon closes by including the first point at the end
            if ~isequal([downsampled_x(1), downsampled_y(1)], [downsampled_x(end), downsampled_y(end)])
                downsampled_x = [downsampled_x; downsampled_x(1)];
                downsampled_y = [downsampled_y; downsampled_y(1)];
            end
        end
        
        function simplifiedPolygon = simplifyPolygon(~, polygon, tolerance)
            if isequal(polygon(1,:), polygon(end,:))
                isClosed = true;
                polygon = polygon(1:end-1, :); % Temporarily remove duplicate end point
            else
                isClosed = false;
            end

            % Simplify the polygon
            simplifiedPolygon = ramerDouglasPeucker_app(app, polygon, tolerance);

            % Add the closing point back if the polygon was closed
            if isClosed
                simplifiedPolygon = [simplifiedPolygon; simplifiedPolygon(1,:)];
            end
        end

        function result = ramerDouglasPeucker_app(~, points, epsilon)
            % Recursive implementation of the Ramer-Douglas-Peucker algorithm.

            if size(points, 1) < 3
                result = points; % Cannot simplify further
                return;
            end

            % Find the point furthest from the line segment (start to end)
            startP = points(1, :);
            endP = points(end, :);

            lineVec = endP - startP;
            lineLen = norm(lineVec);
            if lineLen == 0
                distances = vecnorm(points - startP, 2, 2);
            else
                projection = ((points - startP) * lineVec') / (lineLen^2);
                projection = max(0, min(1, projection));
                closestPoint = startP + projection * lineVec;
                distances = vecnorm(points - closestPoint, 2, 2);
            end

            [maxDistance, idx] = max(distances);

            if maxDistance > epsilon
                % Recursive call on both segments
                firstSegment = ramerDouglasPeucker(points(1:idx, :), epsilon);
                secondSegment = ramerDouglasPeucker(points(idx:end, :), epsilon);

                % Combine results (remove duplicate point at the junction)
                result = [firstSegment(1:end-1, :); secondSegment];
            else
                % Approximate by a straight line
                result = [startP; endP];
            end
        end
        
        function apply_model_loop(app)
            current_sub = app.ddSubs.Value;
            current_img = app.ddImages.Value;

            % Start Processing
            if strcmp(app.ddModelApplyOpt.Value, 'study')
                all_files = dir2(app.txtStudyFolder.Value,'.tif', '/s');
            elseif strcmp(app.ddModelApplyOpt.Value, 'subject')
                all_files = dir2(fullfile(app.txtStudyFolder.Value, app.ddSubs.Items{current_sub}), '.tif', '/s');
            end

            if isempty(all_files)
                app.txtFeedback.Text = 'Error. No images found';
                pause(2)
                app.txtFeedback.Text = '';
                return
            end

            files = all_files(~contains(all_files, 'subject_info'));

            for i = 1:numel(files) 
                prev_processed_muscle = paramGet(app, 'model_roi_drawn', 0, strrep(files{i}, '.tif','.mat'));
                prev_processed_subq = paramGet(app, 'model_roi_subq_drawn', 0, strrep(files{i}, '.tif','.mat'));

                if (prev_processed_muscle) && (prev_processed_subq), continue; end

                file_split = split(string(files{i}),'\');

                % Update Subject
                t = find(strcmp(app.ddSubs.Items, file_split(end-1)));
                app.ddSubs.Value = t;
                ddSubsValueChangedfunction(app)

                % Update Image
                im = file_split(end);
                [~,name,~] = fileparts(im);
                im_index = find(strcmp(strrep(app.ddImages.Items, char(hex2dec('2713')), ''), strcat(name, '.tif')));
                app.ddImages.Value = im_index;
                ddImagesValueChangedfunction(app)

                % Process
                apply_model(app, files{i})
            end

            % Return back to og image 
            app.ddSubs.Value = current_sub;
            ddSubsValueChangedfunction(app)

            app.ddImages.Value = current_img;
            ddImagesValueChangedfunction(app)

            % Processing Complete
            app.txtFeedback.Text = 'Done!';
            pause(2)
            app.txtFeedback.Text = '';
        end
        
        function ddSubsValueChangedfunction(app)
            tracing_state(app, 'off')
            update_image_list(app, 1)
            if (app.chkAutoLoad.Value == 1); btnLoadButtonPushed(app); end
        end

        function ddImagesValueChangedfunction(app)
            tracing_state(app, 'off')
            if (app.chkAutoLoad.Value == 1); btnLoadButtonPushed(app); end
        end
        
        function process_muscle_csa(app, use_model)
            if nargin < 2
                use_model = 0;
            end

            xres = paramGet(app, 'resx', double.empty(0, 2));
            yres = paramGet(app, 'resy', double.empty(0, 2));

            if isempty(xres) && isempty(yres)
                app.txtFeedback.Text = 'Resolutions in x and y are missing. Cannot Measure';
                return
            end
            
            roi_type = paramGet(app, 'roi_type', double.empty(0,2));
            roi_subq_type = paramGet(app, 'roi_subq_type', double.empty(0,2));
            if ~(use_model)
                % check to see if muscle and subq are user or only 1 of
                % them
                if strcmp(roi_type, 'user') 
                    prefix_muscle = ''; 
                else
                    prefix_muscle = 'model_';
                end

                if strcmp(roi_subq_type, 'user') 
                    prefix_subq = ''; 
                else
                    prefix_subq = 'model_';
                end
            else
                prefix_muscle = 'model_';
                prefix_subq = 'model_';
            end

            roi_x = paramGet(app, [prefix_muscle 'roi_x'], double.empty(0, 2));
            roi_y = paramGet(app, [prefix_muscle 'roi_y'], double.empty(0, 2));
            roi_subq_x = paramGet(app, [prefix_subq 'roi_subq_x'], double.empty(0, 2));
            roi_subq_y = paramGet(app, [prefix_subq 'roi_subq_y'], double.empty(0, 2));   

             % Get Muscle Area
            muscle_pixelArea = polyarea(roi_x, roi_y);
            muscle_physicalArea_cm = (muscle_pixelArea * (xres * yres));
            paramSave(app, [prefix_muscle 'muscle_physicalArea_cm_squared'], muscle_physicalArea_cm)

            % Echo Itensity
            mask = poly2mask(roi_x, roi_y, size(app.D_grayscale,1), size(app.D_grayscale,2));
            masked_muscle = app.D_grayscale .* uint8(mask);
            uncorrected_avg_ei = mean(app.D_grayscale(mask));

            % subq fat for correction if it exists
            if ~isempty(roi_subq_x) && ~isempty(roi_subq_y)
                % Get subq Area
                subq_pixelArea = polyarea(roi_subq_x, roi_subq_y);
                subq_physicalArea_cm = (subq_pixelArea * (xres * yres));
                paramSave(app, [prefix_subq 'subq_physicalArea_cm_squared'], subq_physicalArea_cm)
                
                % thickness for correction
                subq_mask = poly2mask(roi_subq_x, roi_subq_y, size(app.D_grayscale,1), size(app.D_grayscale,2));
                masked_subq = app.D_grayscale .* uint8(mask);
                subq_thickness_mm = get_subq_thickness(app, mask, subq_mask, xres, yres);
                subq_thickness_cm = subq_thickness_mm/10;

                muller_avg_ei = uncorrected_avg_ei - (5.0054*(subq_thickness_cm^2)) + (38.30836*subq_thickness_cm);
                young_avg_ei = uncorrected_avg_ei + (subq_thickness_cm*40.5278);
            else
                subq_mask = 0;
                masked_subq = 0;
                subq_thickness_cm = 0;
                young_avg_ei = 0;
                muller_avg_ei = 0;
            end

            % check 
            % figure;
            % imshow(app.D);
            % hold on;
            % colorOverlay = cat(3, zeros(size(mask)), ones(size(mask)), zeros(size(mask)));
            % h = imshow(colorOverlay);
            % set(h, 'AlphaData', 0.5 * mask);
            % hold off;

            % Save
            paramSave(app, [prefix_subq 'CSA_mask_subq'], subq_mask)
            paramSave(app, [prefix_subq 'CSA_masked_subq'], masked_subq)
            paramSave(app, [prefix_subq 'CSA_subq_thickness'], subq_thickness_cm)
            paramSave(app, [prefix_subq 'corrected_young_avg_ei'], young_avg_ei)
            paramSave(app, [prefix_subq 'corrected_muller_avg_ei'], muller_avg_ei)

            % Save everything
            paramSave(app, [prefix_muscle 'CSA_mask'], mask)
            paramSave(app, [prefix_muscle 'CSA_masked_muscle'], masked_muscle)
            paramSave(app, [prefix_muscle 'uncorrected_avg_ei'], uncorrected_avg_ei)
            % paramSave(app, [prefix 'uncorrected_sd_ei'], uncorrected_sd_ei)
            paramSave(app, 'processed', 1)

            % check 
            % figure;
            % imshow(app.D);
            % hold on;
            % colorOverlay = cat(3, zeros(size(mask)), ones(size(mask)), zeros(size(mask)));
            % h = imshow(colorOverlay);
            % set(h, 'AlphaData', 0.5 * mask);
            % hold off
        end
        
        function avg_subq_thickness_cm = get_subq_thickness_3_locations(app, muscle_mask, subq_mask, xres, yres)
            % Get x boundaries of muscle mask
            muscle_mask_min_x = find(any(muscle_mask, 1), 1, 'first');
            muscle_mask_max_x = find(any(muscle_mask, 1), 1, 'last');
            muscle_mask_center_x = round((muscle_mask_min_x + muscle_mask_max_x) / 2);

            % Select the three x positions
            x_positions = [muscle_mask_min_x, muscle_mask_center_x, muscle_mask_max_x];

            % Find thickness at each of the three positions
            thickness_values = zeros(1, 3); % Default to 0 if subq_mask is missing

            % Left column
            boundary_indices = find(subq_mask(:, x_positions(1)));
            if ~isempty(boundary_indices)
                thickness_values(1) = (max(boundary_indices) - min(boundary_indices) + 1) * yres * 10; % Convert to mm
            end

            % Center column
            boundary_indices = find(subq_mask(:, x_positions(2)));
            if ~isempty(boundary_indices)
                thickness_values(2) = (max(boundary_indices) - min(boundary_indices) + 1) * yres * 10; % Convert to mm
            end

            % Right column
            boundary_indices = find(subq_mask(:, x_positions(3)));
            if ~isempty(boundary_indices)
                thickness_values(3) = (max(boundary_indices) - min(boundary_indices) + 1) * yres * 10; % Convert to mm
            end

            % Compute average thickness in cm
            avg_subq_thickness_mm = mean(thickness_values);
            avg_subq_thickness_cm = avg_subq_thickness_mm / 10;
        end
 

        function avg_subq_thickness_cm = get_subq_thickness(app, muscle_mask, subq_mask, xres, yres)
            [muscle_mask_min_x, muscle_mask_max_x] = deal(find(any(muscle_mask, 1), 1, 'first'), find(any(muscle_mask, 1), 1, 'last'));

            % set vaules outside the min and mask to 0
            outside_boundaries_mask = (1:size(subq_mask,2) < muscle_mask_min_x) | (1:size(subq_mask,2) > muscle_mask_max_x);
            subq_mask(:, outside_boundaries_mask) = 0;

            %% Calculate thickness at every x location across the entire image and the roi_only
            non_zero_columns = any(subq_mask, 1);
            thickness_at_x = zeros(1, size(subq_mask, 2));
            thickness_at_x_roi_only = zeros(1, sum(non_zero_columns));
            index = 1;
            for x = 1:size(subq_mask, 2)
                % Find indices of non-zero elements (boundary) in the current column
                boundary_indices = find(subq_mask(:, x));
                % Calculate thickness at the current x location
                if ~isempty(boundary_indices)
                    thickness_at_x(x) = (max(boundary_indices) - min(boundary_indices)+1) * yres*10;
                else
                    thickness_at_x(x) = 0; % No boundary detected in this column
                end

                if non_zero_columns(x)
                    boundary_indices = find(subq_mask(:, x));
                    if ~isempty(boundary_indices)
                        thickness_at_x_roi_only(index) = (max(boundary_indices) - min(boundary_indices)+1) * yres*10;
                    else
                        thickness_at_x_roi_only(index) = 0; % No boundary detected in this column
                    end
                    index = index + 1;
                end
            end

            % average thickness in millimeters
            avg_subq_thickness_mm = mean(thickness_at_x_roi_only);
            avg_subq_thickness_cm = avg_subq_thickness_mm/10;
        end  
        
        function visibility_editing_buttons(app, state)
            % app.ddType.Visible = state;
            app.btnAdjustROI.Visible = state;
            app.btnDelete.Visible = state;
            app.btnDrawROI.Visible = state;
        end
        
        function test_viewing_options(app)
             % Update viewing panel options
            % if model has been applied already, show ROI options and Compile panel
            model_applied = paramGet(app, 'model_applied', 0);
            muscle = strcmp(app.ddType.Value, 'muscle');
            if (muscle)
                roi_type = paramGet(app, 'roi_type', double.empty(0,2));
                model_roi_drawn = paramGet(app, 'model_roi_drawn', 0);
                roi_drawn = paramGet(app, 'roi_drawn', 0);
            else
                roi_type = paramGet(app, 'roi_subq_type', double.empty(0,2));
                model_roi_drawn = paramGet(app, 'model_roi_subq_drawn', 0);
                roi_drawn = paramGet(app, 'roi_subq_drawn', 0);
            end
         
            if (model_applied)
                % update visibility
                app.panROIOptions.Visible = 'on';
                app.panCompile.Visible = 'on';
                if strcmp(roi_type, 'model')
                    app.btnUseModel.Value = 1;
                    app.btnEditModel.Value = 0;
                    visibility_editing_buttons(app, 'off');
                     % Correct enabling use model depending on whether or not
                     % the roi is drawn
                     if isempty(model_roi_drawn) || ~(model_roi_drawn)
                         app.btnUseModel.Enable = 0;
                     else
                         app.btnUseModel.Enable = 1;
                     end
                elseif strcmp(roi_type, 'user')
                    app.btnUseModel.Value = 0;
                    app.btnEditModel.Value = 1;
                    visibility_editing_buttons(app, 'on');
                     % Correct enabling use model depending on whether or not
                     % the roi is drawn
                     if isempty(roi_drawn) || ~(roi_drawn)
                         app.btnUseModel.Enable = 0;
                     else
                         app.btnUseModel.Enable = 1;
                     end
                elseif strcmp(roi_type, 'empty')
                    app.btnUseModel.Value = 0;
                    app.btnEditModel.Value = 0;
                    app.btnNone.Value = 1;
                    visibility_editing_buttons(app, 'off');
                else
                    app.btnUseModel.Value = 0;
                    app.btnEditModel.Value = 0;
                    app.btnNone.Value = 0;
                    visibility_editing_buttons(app, 'off');
                end 
               
            else
                app.panROIOptions.Visible = 'off';
                app.panCompile.Visible = 'off';
            end

            if isempty(roi_type)
                app.btnAdjustROI.Visible = 0;
                app.btnDelete.Visible = 0;
                app.btnDrawROI.Visible = 0;
                % app.btnProcess.Visible = 0;
            end

            app.panModel.Visible = 'on';
            app.panEnh.Visible = 'on';
        end
        
        function override_check_use_model(app)
            app.ddType.Value = 'subq';
            app.btnUseModel.Value = 1;
            btnUseModelValueChanged(app) 
            app.ddType.Value = 'muscle';
            app.btnUseModel.Value = 1;
            btnUseModelValueChanged(app) 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Import Model
            S = load(app.model_path_cpu); 
            app.net_cpu = S.model;          
            %app.net_cpu = importNetworkFromONNX(app.model_path_cpu);
            app.btnBack.Visible = 0;
            app.btnForward.Visible = 0;
            app.btnHome.Visible = 0;
            app.btnPan.Visible = 0;
            app.btnZoom.Visible = 0;
            app.panModel.Visible = 0;
            app.panCompile.Visible = 0;
            app.panROIOptions.Visible = 0;
            app.panEnh.Visible = 0;
   
        end

        % Button pushed function: btnStudyFolder
        function btnStudyFolderButtonPushed(app, event)
            newPath = uigetdir(app.txtStudyFolder.Value, 'Choose Study Folder');
            figure(app.UIFigure);
            if (newPath ~= 0)
                app.txtStudyFolder.Value = newPath;
                tracing_state(app, 'off')
                txtStudyFolderValueChanged(app, event)
            end
        end

        % Value changed function: txtStudyFolder
        function txtStudyFolderValueChanged(app, event)
           tracing_state(app, 'off')
           all_files = dir2(app.txtStudyFolder.Value, '/s');
           files = all_files(~contains(all_files,{'csv', 'mat'}));

           subject_cells = cellfun(@(path_folder) str_split_mw(path_folder,'\', -1), files, 'UniformOutput', false);
           subject_folders = unique(cellfun(@(x) x{1}, subject_cells, 'UniformOutput', false));

           num_subs = length(subject_folders);

           %Check if no subjects
           if (num_subs < 1)
               app.ddSubs.Items = {};
               app.ddSubs.ItemsData = [];
               errordlg('Please select study folder containing subject folders. Be sure not to select an individual subject folder.','No subjects found' );
               tracing_state(app, 'on')
               return
           end

           % Populate dropdown
           app.ddSubs.Items = subject_folders;
           app.ddSubs.ItemsData = 1:num_subs;
           app.ddSubs.Value = 1;

           % Call next image drop down
           ddSubsValueChanged(app, event)   
        end

        % Value changed function: ddSubs
        function ddSubsValueChanged(app, event)
            tracing_state(app, 'off')
            update_image_list(app, 1)
            if (app.chkAutoLoad.Value == 1)
                btnLoadButtonPushed(app, event); 
            else
                tracing_state(app, 'on')
            end
        end

        % Button pushed function: btnLoad
        function btnLoadButtonPushed(app, event)
            if (isempty(app.ddImages.Value)), return, end
            
            cla(app.axes)

            % reset buttons
            app.ddType.Value = 'muscle';
            app.btnUseModel.Value = 0;
            app.btnEditModel.Value = 0;
            app.btnNone.Value = 0;
            app.roi = [];
            app.roi_subq = [];

            % Get the subject folder
            subject = string(app.ddSubs.Items(app.ddSubs.Value));
            subject_folder = fullfile(app.txtStudyFolder.Value, subject);

            % Get the currently selected image
            file_name = string(app.ddImages.Items(app.ddImages.Value));
            file_name = strrep(file_name, char(hex2dec('2713')), '');

            selected_image = fullfile(subject_folder, file_name);
            
            try
                app.D = imread(selected_image);
                app.D_grayscale = 0.2989 * app.D(:, :, 1, :) + 0.5870 * app.D(:, :, 2, :) + 0.1140 * app.D(:, :, 3, :);
            catch
                app.txtFeedback.Text = sprintf('Issue Loading image %s for %s. Skipping', file_name, subject);
                pause(2)
                return
            end


            % Save descriptive info
            app.params.path = strrep(selected_image, '.tif', '.mat');
           
            % Save image info
            paramSave(app, 'file_name', file_name)
            paramSave(app, 'subject', subject)

            % Refresh Axes
            refreshAxes(app);

            % Update visbility based on editing options
            tracing_state(app, 'on')

            % Update visibility based on step in processing for image
            % test_viewing_options(app)
        end

        % Value changed function: ddImages
        function ddImagesValueChanged(app, event)
            if (app.chkAutoLoad.Value == 1)
                tracing_state(app, 'off')
                btnLoadButtonPushed(app, event)
            else
                tracing_state(app, 'on')
            end
        end

        % Button pushed function: btnForward
        function btnForwardButtonPushed(app, event)
           img_list = app.ddImages.Items;
           current_img = app.ddImages.Value;

           if current_img < numel(img_list)
               next_img = current_img + 1;  
           else
               next_img = 1; 
           end

           img = img_list(next_img);
           [~,name,ext] = fileparts(string(img));
           im_index = find(strcmp(app.ddImages.Items, strcat(name, ext)));
           app.ddImages.Value = im_index;
           ddImagesValueChanged(app, event)
        end

        % Button pushed function: btnBack
        function btnBackButtonPushed(app, event)
           img_list = app.ddImages.Items;
           current_img = app.ddImages.Value;

           if current_img == 1 
               next_img = numel(img_list);  
           else
               next_img = current_img - 1; 
           end

           img = img_list(next_img);
           [~,name,ext] = fileparts(string(img));
           im_index = find(strcmp(app.ddImages.Items, strcat(name, ext)));
           app.ddImages.Value = im_index;
           ddImagesValueChanged(app, event)
        end

        % Button pushed function: btnModelApply
        function btnModelApplyButtonPushed(app, event)
            % tracing_state(app, 'off', 1)
            % app.btnHome.Enable = 0;
            % clear_measures(app, 1);
            % update_image_list(app);
            action = app.ddModelApplyOpt.Value; 

            switch action
                case 'study'
                    apply_model_loop(app)
                case 'subject'
                    apply_model_loop(app)
                case 'image'
                    img_path = char(fullfile(app.txtStudyFolder.Value,app.ddSubs.Items(app.ddSubs.Value), app.ddImages.Items(app.ddImages.Value)));
                    img_path = strrep(img_path, char(hex2dec('2713')), '');
                    apply_model(app, img_path)
            end
            % update_image_list(app);
            refreshAxes(app)
            % app.panROIOptions.Visible = 'on';
            % app.panCompile.Visible = 'on';
        end

        % Value changed function: btnDrawROI
        function btnDrawROIValueChanged(app, event)
            app.chkViewModel.Value = 0;
            tracing_state(app, 'off', 1)
            app.btnHome.Enable = 0;
            if strcmp(app.ddType.Value,'muscle') 
                clear_measures(app);

                % Draw ROI
                app.roi = images.roi.Polygon(app.axes,'LineWidth',1,'Color','m', 'MarkerSize', 5, ...
                    'FaceAlpha', 0, 'Deletable', 0);
                draw(app.roi)

                app.btnAccept.Text = 'Accept & Save ROI'; % new
                app.btnAccept.Visible = 'on';
            else
                clear_measures(app);

                % Draw ROI
                app.roi_subq = images.roi.Polygon(app.axes,'LineWidth',1,'Color','g', 'MarkerSize', 5, ...
                    'FaceAlpha', 0, 'Deletable', 0);
                draw(app.roi_subq)

                app.btnAccept.Text = 'Accept & Save ROI'; % new
                app.btnAccept.Visible = 'on';
            end
        end

        % Value changed function: btnAccept
        function btnAcceptValueChanged(app, event)
            tracing_state(app, 'on')
            app.btnAccept.Visible = 'off';

            if strcmp(app.btnAccept.Text, 'Accept & Save ROI')
                if strcmp(app.ddType.Value, 'subq')
                    if numel(app.roi_subq.Position(:,1)) >= 2
                        interpolate_and_save(app, app.roi_subq, 'roi_subq');
                    else
                        app.txtFeedback.Text = 'Must draw more than 1 point. Try Again';
                        pause(2)
                        app.txtFeedback.Text = '';
                    end
                elseif strcmp(app.ddType.Value, 'muscle')
                    if numel(app.roi.Position(:,1)) >= 2
                        interpolate_and_save(app, app.roi, 'roi');
                    else
                        app.txtFeedback.Text = 'Must draw more than 1 point. Try Again';
                        pause(2)
                        app.txtFeedback.Text = '';
                    end
                end
            end

            update_image_list(app);
            refreshAxes(app, app.axes)
            app.btnAccept.Value = 0;
            app.btnDrawROI.Value = 0;
            tracing_state(app, 'on', 1)
            app.btnHome.Enable = 1;
        end

        % Button pushed function: btnAdjustROI
        function btnAdjustROIButtonPushed(app, event)
            app.chkViewModel.Value = 0;
            if (strcmp(app.ddType.Value, 'muscle'))
                roi_x = paramGet(app, 'roi_position_x', double.empty(0, 2));
                roi_y = paramGet(app, 'roi_position_y', double.empty(0, 2));
                if isempty(roi_x) && isempty(roi_y) 
                    % Get Model Predictions
                    roi_x = paramGet(app, 'model_roi_position_x', double.empty(0, 2));
                    roi_y = paramGet(app, 'model_roi_position_y', double.empty(0, 2));
                    if isempty(roi_x) && isempty(roi_y)
                        % if model predictions empty
                        errordlg('Need to apply model or model did not detect ROI','Error');
                        return
                    end
                end
            elseif (strcmp(app.ddType.Value, 'subq'))
                roi_subq_x = paramGet(app, 'roi_subq_position_x', double.empty(0, 2));
                roi_subq_y = paramGet(app, 'roi_subq_position_y', double.empty(0, 2));
                if isempty(roi_subq_x) && isempty(roi_subq_y)
                    % Get Model Predictions
                    roi_subq_x = paramGet(app, 'model_roi_subq_position_x', double.empty(0, 2));
                    roi_subq_y = paramGet(app, 'model_roi_subq_position_y', double.empty(0, 2));
                    if isempty(roi_subq_x) && isempty(roi_subq_y)
                        errordlg('Need to apply model or model did not detect ROI','Error');
                        return
                    end
                end
            end

            clear_measures(app)
            tracing_state(app, 'off', 1)

            if (strcmp(app.ddType.Value, 'muscle'))
                if isrow(roi_x); roi_x = roi_x'; end
                if isrow(roi_y); roi_y = roi_y'; end
                app.roi = drawpolygon(app.axes, 'Position',[roi_x roi_y], ...
                    'LineWidth',1,'Color','m', 'MarkerSize', 4, 'FaceAlpha', 0);
            elseif strcmp(app.ddType.Value, 'subq') 
                if isrow(roi_subq_x); roi_subq_x = roi_subq_x'; end
                if isrow(roi_subq_y); roi_subq_y = roi_subq_y'; end
                app.roi_subq = drawpolygon(app.axes, 'Position',[roi_subq_x roi_subq_y], ...
                'LineWidth',1,'Color','g', 'MarkerSize', 4, 'FaceAlpha', 0);
            end

            app.btnAccept.Text = 'Accept & Save ROI'; 
            app.btnAccept.Visible = 'on';
        end

        % Button pushed function: btnDelete
        function btnDeleteButtonPushed(app, event)
            app.chkViewModel.Value = 0;
            % Clear roi
            if strcmp(app.ddType.Value, 'muscle')
                delete(app.roi);
                app.roi = [];
                app.btnDrawROI.Value = 0;
   
                % save vid info
                paramSave(app, 'roi_drawn', 0)
                paramSave(app, 'roi_position_x', [])
                paramSave(app, 'roi_position_y', [])
            elseif strcmp(app.ddType.Value, 'subq')
                delete(app.roi_subq);
                app.roi_subq = [];
                app.btnDrawROI.Value = 0;

                % save vid info
                paramSave(app, 'roi_subq_drawn', 0)
                paramSave(app, 'roi_subq_position_x', [])
                paramSave(app, 'roi_subq_position_y', [])
            end

            update_image_list(app, 1)
            refreshAxes(app)
        end

        % Value changed function: chkViewModel
        function chkViewModelValueChanged(app, event)
            app.chkHide.Value = 0;
            if (app.chkViewModel.Value) 
                app.btnDelete.Enable = "off";
            else
                app.btnDelete.Enable = "on";
            end
            refreshAxes(app)
        end

        % Value changed function: chkHide
        function chkHideValueChanged(app, event)
            app.chkViewModel.Value = 0;
            refreshAxes(app)
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;

            empty_event = struct();
            switch key
                case {'leftarrow'}
                    btnBackButtonPushed(app, empty_event)
                case {'rightarrow'}
                    btnForwardButtonPushed(app, empty_event)
                case {'u'}
                    btnUseModelValueChanged(app, empty_event)

                % case {'h'}
                %     chkHideValueChanged(app, empty_event)
                % case {'s'}
                %     chkHideValueChanged(app, empty_event)
            end
        end

        % Value changed function: sldContrast
        function sldContrastValueChanged(app, event)
            refreshAxes(app);
        end

        % Value changed function: ddFilters
        function ddFiltersValueChanged(app, event)
            refreshAxes(app);            
        end

        % Value changed function: chkViewOriginal
        function chkViewOriginalValueChanged(app, event)
            app.ddFilters.Value = 'None'; 
            app.sldContrast.Value = 50;
            refreshAxes(app)
        end

        % Button pushed function: btnProcess
        function btnProcessButtonPushed(app, event)
            app.chkViewModel.Value = 0;

            % Get type for muscle (model or user)
            roi_type = paramGet(app, 'roi_type', double.empty(0,2));
            roi_subq_type = paramGet(app, 'roi_subq_type', double.empty(0,2));
          
            % Make sure atleast muscle roi_drawn
            if strcmp(roi_type, 'model')
                roi_drawn = paramGet(app, 'model_roi_drawn', 0);
            elseif strcmp(roi_type, 'user')
                roi_drawn = paramGet(app, 'roi_drawn', 0);
            end

            if ~(roi_drawn) 
                app.txtFeedback.Text = 'No roi found (e.g., roi_drawn parameter is empty).';
                tracing_state(app, 'on')
                pause(2)
                app.txtFeedback.Text = '';
                return
            end
           
            app.txtFeedback.Text = 'Processing...';
            tracing_state(app, 'off')
            pause(1)

            if strcmp(roi_type, 'model') && strcmp(roi_subq_type, 'model')
                process_muscle_csa(app, 1) % save model predictions if they are there
            else
                process_muscle_csa(app, 1)  % save model predictions if they are there
                process_muscle_csa(app) % save user-defined predictions if they are there
            end

            app.txtFeedback.Text = 'Saved';
            tracing_state(app, 'on')
            pause(1)
            app.txtFeedback.Text = '';
        end

        % Button pushed function: btnProcessStudy
        function btnProcessStudyButtonPushed(app, event)
            current_sub = app.ddSubs.Value;
            current_img = app.ddImages.Value;

            % Start Processing
            all_files = dir2(app.txtStudyFolder.Value,'.mat', '/s');
            files = all_files(~contains(all_files, 'subject_info'));

            for i = 1:numel(files)
                if (app.ddProcess.Value == '2') 
                    prev_processed = paramGet(app, 'processed', 0, files{i});
                    if (prev_processed)
                        if (app.ddProcess.Value == '2')
                            continue
                        end
                    end
                end
                
                % Check that roi has been chosen 
                roi_type = paramGet(app, 'roi_type', double.empty(0,2), files{i});
                roi_subq_type = paramGet(app, 'roi_subq_type', double.empty(0,2), files{i});
                if isempty(roi_type) &&isempty(roi_subq_type), continue; end

                file_split = split(string(files{i}),'\');

                % Update Subject
                t = find(strcmp(app.ddSubs.Items, file_split(end-1)));
                app.ddSubs.Value = t;
                ddSubsValueChanged(app, event)

                % Update Image
                im = file_split(end);
                [~,name,~] = fileparts(im);
                im_index = find(strcmp(strrep(app.ddImages.Items, char(hex2dec('2713')), ''), strcat(name, '.tif')));
                app.ddImages.Value = im_index;
                ddImagesValueChanged(app, event)

                % Process
                btnProcessButtonPushed(app, event)
            end

            % Return back to og image 
            app.ddSubs.Value = current_sub;
            ddSubsValueChanged(app, event)
            app.ddImages.Value = current_img;
            ddImagesValueChanged(app, event)

            % Processing Complete
            app.txtFeedback.Text = 'Done!';
            pause(2)
            app.txtFeedback.Text = '';
            tracing_state(app, 'on')
        end

        % Button pushed function: btnCompile
        function btnCompileButtonPushed(app, event)
            app.txtFeedback.Text = 'Compiling...';
            all_files = dir2(app.txtStudyFolder.Value,'.mat', '/s');
            files = all_files(~contains(all_files, 'subject_info'));
            warn_state = warning('off', 'MATLAB:load:variableNotFound');
       
            for i = 1:numel(files)
                mat_info = load(files{i}, 'processed');
                if (~isfield(mat_info, 'processed') || ~(mat_info.processed))
                    continue;
                end

                % CSA only
                mat_data = load(files{i}, 'subject', 'file_name', 'roi_type', ...
                    'model_muscle_physicalArea_cm_squared', 'model_subq_physicalArea_cm_squared', ...
                    'muscle_physicalArea_cm_squared', 'subq_physicalArea_cm_squared');

                if ~(isfield(mat_data, 'roi_type'));  mat_data.roi_type = []; end
                if ~(isfield(mat_data, 'model_muscle_physicalArea_cm_squared'));  mat_data.model_muscle_physicalArea_cm_squared = []; end
                if ~(isfield(mat_data, 'model_subq_physicalArea_cm_squared'));  mat_data.model_subq_physicalArea_cm_squared = []; end
                if ~(isfield(mat_data, 'muscle_physicalArea_cm_squared')); mat_data.muscle_physicalArea_cm_squared = []; end
                if ~(isfield(mat_data, 'subq_physicalArea_cm_squared')); mat_data.subq_physicalArea_cm_squared = []; end

                mat_data_EI =  load(files{i}, 'roi_subq_type', ...
                    'model_CSA_subq_thickness', 'model_uncorrected_avg_ei','model_corrected_young_avg_ei','model_corrected_muller_avg_ei', ...
                    'CSA_subq_thickness', 'uncorrected_avg_ei','corrected_young_avg_ei','corrected_muller_avg_ei');
                
                if ~(isfield(mat_data_EI, 'roi_subq_type'));  mat_data_EI.roi_subq_type = []; end
                if ~(isfield(mat_data_EI, 'model_CSA_subq_thickness'));  mat_data_EI.model_CSA_subq_thickness = []; end
                if ~(isfield(mat_data_EI, 'model_uncorrected_avg_ei')); mat_data_EI.model_uncorrected_avg_ei = []; end
                if ~(isfield(mat_data_EI, 'model_corrected_young_avg_ei')); mat_data_EI.model_corrected_young_avg_ei = []; end
                if ~(isfield(mat_data_EI, 'model_corrected_muller_avg_ei')); mat_data_EI.model_corrected_muller_avg_ei = []; end
        
                if ~(isfield(mat_data_EI, 'CSA_subq_thickness'));  mat_data_EI.CSA_subq_thickness = []; end
                if ~(isfield(mat_data_EI, 'uncorrected_avg_ei')); mat_data_EI.uncorrected_avg_ei = []; end
                if ~(isfield(mat_data_EI, 'corrected_young_avg_ei')); mat_data_EI.corrected_young_avg_ei = []; end
                if ~(isfield(mat_data_EI, 'corrected_muller_avg_ei')); mat_data_EI.corrected_muller_avg_ei = []; end
                

                out = table(mat_data.subject, mat_data.file_name, {mat_data.roi_type}, {mat_data_EI.roi_subq_type}, ...
                    {mat_data.model_muscle_physicalArea_cm_squared}, {mat_data.model_subq_physicalArea_cm_squared}, ...
                    {mat_data_EI.model_CSA_subq_thickness}, ...
                    {mat_data_EI.model_uncorrected_avg_ei}, {mat_data_EI.model_corrected_young_avg_ei}, {mat_data_EI.model_corrected_muller_avg_ei}, ...
                    {mat_data.muscle_physicalArea_cm_squared},  {mat_data.subq_physicalArea_cm_squared}, ...
                    {mat_data_EI.CSA_subq_thickness}, ...
                    {mat_data_EI.uncorrected_avg_ei}, {mat_data_EI.corrected_young_avg_ei}, {mat_data_EI.corrected_muller_avg_ei});
                out.Properties.VariableNames  = {'subject',  'file_name', 'muscle_roi_type', 'subq_roi_type'...
                    'model_muscle_area_cm_squared', 'model_subq_area_cm_squared', ...
                    'model_subq_thickness_cm', ...
                    'model_uncorrected_avg_ei', 'model_corrected_young_avg_ei','model_corrected_muller_avg_ei'...
                    'muscle_area_cm_squared', 'subq_area_cm_squared', ...
                    'subq_thickness_cm', ...
                    'uncorrected_avg_ei', 'corrected_young_avg_ei','corrected_muller_avg_ei'};

                if exist('out_all_csa','var')
                    out_all_csa = [out_all_csa; out];
                else
                    out_all_csa = out;
                end
                
                clearvars mat_info mat_data out
            end

            todaysDate = datestr(date, 'mmddyyyy');
            out_folder = fullfile(app.txtStudyFolder.Value, strcat(todaysDate, '_', 'CSA', '.csv'));
            
            % Check if the user did not cancel
            if  exist('out_all_csa','var')
                [file, path] = uiputfile(out_folder, 'Save As');
                if ischar(file)
                    % Full file path where the file will be saved
                    saveFilePath = fullfile(path, file);

                    writetable(out_all_csa, saveFilePath);

                    app.txtFeedback.Text = strcat('File saved to: ', saveFilePath);
                    pause(1)
                    app.txtFeedback.Text = '';
                else
                    app.txtFeedback.Text = 'User canceled the save operation.';
                    pause(1)
                    app.txtFeedback.Text = '';
                end

            else
                app.txtFeedback.Text = '';
                errordlg('Be sure to Process Data First','No Data');
                return
            end
            warning(warn_state);
        end

        % Value changed function: btnHelpProcess
        function btnHelpProcessValueChanged(app, event)
            value = app.btnHelpProcess.Value;
            if value == 1
                app.txtProcessOpts.Enable = 'on';
                app.txtProcessOpts.Visible = 'on';
            else
                app.txtProcessOpts.Enable = 'off';
                app.txtProcessOpts.Visible = 'off';
            end
        end

        % Value changed function: chkAutoLoad
        function chkAutoLoadValueChanged(app, event)
            if (app.chkAutoLoad.Value == 1)
                app.btnLoad.Enable = 'off';
            else
                app.btnLoad.Enable = 'on';
            end
        end

        % Value changed function: btnUseModel
        function btnUseModelValueChanged(app, event)
            value = app.btnUseModel.Value;
            if (value)
                app.btnEditModel.Value = 0;
                app.chkViewModel.Value = 0;
                app.btnNone.Value = 0;
                visibility_editing_buttons(app, 'off')
                app.btnProcess.Visible = 1;

                % clear roi drawn 
                 if strcmp(app.ddType.Value, 'muscle')
                    paramSave(app, 'roi_type', 'model')
                    paramSave(app, 'roi_position_x', []);
                    paramSave(app, 'roi_position_y', []);
                    paramSave(app, 'roi_x', []);
                    paramSave(app, 'roi_y', []);
                    paramSave(app, 'roi_drawn', 0);
                    % clear measures
                    paramSave(app, 'muscle_physicalArea_cm_squared', [])
                    paramSave(app, 'uncorrected_avg_ei', [])
                else
                    paramSave(app, 'roi_subq_type', 'model')
                    paramSave(app, 'roi_subq_position_x', []);
                    paramSave(app, 'roi_subq_position_y', []);
                    paramSave(app, 'roi_subq_x', []);
                    paramSave(app, 'roi_subq_y', []);
                    paramSave(app, 'roi_subq_drawn', 0)
                    paramSave(app, 'subq_physicalArea_cm_squared', [])
                end

                % refresh axes
                refreshAxes(app)
            end
            update_image_list(app);
        end

        % Value changed function: btnEditModel
        function btnEditModelValueChanged(app, event)
            value = app.btnEditModel.Value;
            if (value)
                app.btnUseModel.Value = 0;
                app.btnNone.Value = 0;
                if strcmp(app.ddType.Value, 'muscle')
                    paramSave(app, 'roi_type', 'user')
                else
                    paramSave(app, 'roi_subq_type', 'user')
                end
                update_image_list(app);
                visibility_editing_buttons(app, 'on')
            end
        end

        % Value changed function: btnNone
        function btnNoneValueChanged(app, event)
            value = app.btnNone.Value;
            if (value)
                app.btnUseModel.Value = 0;
                app.btnEditModel.Value = 0;
                visibility_editing_buttons(app, 'off')
                if strcmp(app.ddType.Value, 'muscle')
                    paramSave(app, 'roi_type', 'empty')
                else
                    paramSave(app, 'roi_subq_type', 'empty')
                end
                clear_measures(app)
            end
            update_image_list(app);
            % refresh axes
            refreshAxes(app)
        end

        % Value changed function: ddType
        function ddTypeValueChanged(app, event)
            if strcmp(app.ddType.Value, 'muscle')
                roi_type = paramGet(app, 'roi_type', double.empty(0,2));
            else
                roi_type = paramGet(app, 'roi_subq_type', double.empty(0,2));
            end

            if isempty(roi_type) 
                app.btnUseModel.Value = 0;
                app.btnEditModel.Value = 0;
                app.btnNone.Value = 0;
                test_viewing_options(app)
            else
                switch roi_type
                    case 'model'
                        app.btnUseModel.Value = 1;
                        app.btnEditModel.Value = 0;
                        app.btnNone.Value = 0;
                        btnUseModelValueChanged(app)
                    case 'user'
                        app.btnUseModel.Value = 0;
                        app.btnEditModel.Value = 1;
                        app.btnNone.Value = 0;
                        btnEditModelValueChanged(app)
                    case 'empty'
                        app.btnUseModel.Value = 0;
                        app.btnEditModel.Value = 0;
                        app.btnNone.Value = 1;
                        btnNoneValueChanged(app)
                end
            end
            
        end

        % Button pushed function: btnConvert
        function btnConvertButtonPushed(app, event)
            path_to_dcms = uigetdir(app.txtStudyFolder.Value, 'Choose Dicom Folder');
            figure(app.UIFigure);
            [main_data_path, ~] = fileparts(path_to_dcms);

            % Get subject list
            d = dir(path_to_dcms); d = d([d.isdir] & ~ismember({d.name}, {'.','..'}));
            subject_paths = fullfile(path_to_dcms, {d.name});

            if isempty(subject_paths)
                errordlg('No subjects found')
                return
            end

            %% Main
            for i = 1:numel(subject_paths)
                % Subject ID
                [~, subject_id] = fileparts(subject_paths{i});
            
                % Get valid files
                files = dir2(subject_paths{i}, '/s');
                files = files(~contains(files, {'DICOMDIR', '.csv', 'HTM', 'SVG', 'TXT', 'JPG'}));
            
                % Output directory
                out_path = fullfile(main_data_path, 'img_files', subject_id);
                if ~exist(out_path, 'dir'), mkdir(out_path); end
            
                % File-level progress bar
                for ii = 1:numel(files)
                    [~, fname] = fileparts(files{ii});
                    info = dicominfo(files{ii});
                    img = dicomread(files{ii});
                    sub_table = table({subject_id}, {fname}, 'VariableNames', {'subjectID', 'filename'});
                    im_empty = isempty(img);
                    img_table = detect_resolution(info);
            
                    if isempty(img_table)
                        fprintf('file %s is empty; skipping\n', fname);
                        continue;
                    end
            
                    if ~im_empty
                        count = 1;
                        out_fname = sprintf('%s_img%d', subject_id, count);
                        out_file = fullfile(out_path, [out_fname, '.tif']);
                        while exist(out_file, 'file')
                            count = count + 1;
                            out_fname = sprintf('%s_img%d', subject_id, count);
                            out_file = fullfile(out_path, [out_fname, '.tif']);
                        end
            
                        imwrite(img, out_file);
            
                        matfile_name = strrep(out_file, '.tif', '.mat');
                        f = matfile(matfile_name, "Writable", true);
                        if ~isempty(img_table.resx{1}) && ~isempty(img_table.resy{1})
                            f.dicom_file_name = fname;
                            f.resx = cell2mat(img_table.resx);
                            f.resy = cell2mat(img_table.resy);
                        end
                    else
                        out_dir = {};
                        out_fname = {};
                        notes = 'Error. Image is empty. No data was copied. Skipped';
                    end
            
                    % Log
                    new_folder = table({out_path}, 'VariableNames', {'new_folder'});
                    new_name = table({out_fname}, 'VariableNames', {'new_filename'});
                    log = [sub_table, new_folder, new_name, img_table];
            
                    if exist('log_out', 'var')
                        log_out = [log_out; log];
                    else
                        log_out = log;
                    end
                end
            end
            
            writetable(log_out, fullfile(main_data_path,  strcat('data_log.csv')));
            app.txtFeedback.Text = 'Done. Ready to Start Processing.';
            pause(2)
            app.txtFeedback.Text = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.panLeft = uipanel(app.UIFigure);
            app.axes = uiaxes(app.panLeft);
            xlabel(app.axes, 'lat (mm)')
            ylabel(app.axes, 'depth (mm)')
            app.axes.XAxisLocation = 'origin';
            app.axes.XTick = [];
            app.axes.XTickLabel = '';
            app.axes.YAxisLocation = 'origin';
            app.axes.YTick = [];
            app.axes.YTickLabel = '';
            app.axes.ZTick = [];
            app.axes.ZTickLabel = '';
            app.axes.Visible = 'off';
            app.ddSubs = uidropdown(app.panLeft);
            app.ddSubs.Items = {};
            app.ddSubs.ValueChangedFcn = createCallbackFcn(app, @ddSubsValueChanged, true);
            app.ddSubs.Value = {};
            app.lblSubjects = uilabel(app.panLeft);
            app.lblSubjects.Text = 'Subjects:';
            app.ddImages = uidropdown(app.panLeft);
            app.ddImages.Items = {};
            app.ddImages.ValueChangedFcn = createCallbackFcn(app, @ddImagesValueChanged, true);
            app.ddImages.Value = {};
            app.lblImages = uilabel(app.panLeft);
            app.lblImages.Text = 'Images:';
            app.txtStudyFolder = uieditfield(app.panLeft, 'text');
            app.txtStudyFolder.ValueChangedFcn = createCallbackFcn(app, @txtStudyFolderValueChanged, true);
            app.txtStudyFolder.Value = 'C:\Users\';
            app.btnStudyFolder = uibutton(app.panLeft, 'push');
            app.btnStudyFolder.ButtonPushedFcn = createCallbackFcn(app, @btnStudyFolderButtonPushed, true);
            app.btnStudyFolder.Text = 'Study Folder';
            app.btnPan = uibutton(app.panLeft, 'push');
            app.btnPan.Icon = fullfile(pathToMLAPP, 'bin', 'images', 'hand.png');
            app.btnPan.Text = '';
            app.btnZoom = uibutton(app.panLeft, 'push');
            app.btnZoom.Icon = fullfile(pathToMLAPP, 'bin', 'images', 'zoom.png');
            app.btnZoom.Text = '';
            app.btnHome = uibutton(app.panLeft, 'push');
            app.btnHome.Icon = fullfile(pathToMLAPP, 'bin', 'images', 'logo-clipart-home-13.png');
            app.btnHome.Text = '';
            app.btnAccept = uibutton(app.panLeft, 'state');
            app.btnAccept.ValueChangedFcn = createCallbackFcn(app, @btnAcceptValueChanged, true);
            app.btnAccept.Visible = 'off';
            app.btnAccept.Text = 'Accept & Save ROI';
            app.btnBack = uibutton(app.panLeft, 'push');
            app.btnBack.ButtonPushedFcn = createCallbackFcn(app, @btnBackButtonPushed, true);
            app.btnBack.Text = {'<'; ''};
            app.btnForward = uibutton(app.panLeft, 'push');
            app.btnForward.ButtonPushedFcn = createCallbackFcn(app, @btnForwardButtonPushed, true);
            app.btnForward.Text = {'>'; ''};
            app.txtFeedback = uilabel(app.panLeft);
            app.txtFeedback.Text = '';
            app.btnConvert = uibutton(app.panLeft, 'push');
            app.btnConvert.ButtonPushedFcn = createCallbackFcn(app, @btnConvertButtonPushed, true);
            app.btnConvert.Text = 'Convert DCMs';
            app.panRight = uipanel(app.UIFigure);
            app.chkAutoLoad = uicheckbox(app.panRight);
            app.chkAutoLoad.ValueChangedFcn = createCallbackFcn(app, @chkAutoLoadValueChanged, true);
            app.chkAutoLoad.Text = '  Auto Load';
            app.chkAutoLoad.Value = true;
            app.btnLoad = uibutton(app.panRight, 'push');
            app.btnLoad.ButtonPushedFcn = createCallbackFcn(app, @btnLoadButtonPushed, true);
            app.btnLoad.Enable = 'off';
            app.btnLoad.Text = 'Load';
            app.panEnh = uipanel(app.panRight);
            app.panEnh.Title = 'Image Enhancements & Viewing Options';
            app.chkViewOriginal = uicheckbox(app.panEnh);
            app.chkViewOriginal.ValueChangedFcn = createCallbackFcn(app, @chkViewOriginalValueChanged, true);
            app.chkViewOriginal.Text = 'Reset';
            app.ddFilters = uidropdown(app.panEnh);
            app.ddFilters.Items = {'None', 'Auto', 'BW', 'Auto+BW'};
            app.ddFilters.ValueChangedFcn = createCallbackFcn(app, @ddFiltersValueChanged, true);
            app.ddFilters.Value = 'None';
            app.sldContrast = uislider(app.panEnh);
            app.sldContrast.Limits = [0 200];
            app.sldContrast.MajorTicks = [];
            app.sldContrast.ValueChangedFcn = createCallbackFcn(app, @sldContrastValueChanged, true);
            app.sldContrast.MinorTicks = [];
            app.sldContrast.Value = 50;
            app.ContrastSliderLabel = uilabel(app.panEnh);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.lblFilters = uilabel(app.panEnh);
            app.lblFilters.Text = 'Filters';
            app.chkHide = uicheckbox(app.panEnh);
            app.chkHide.ValueChangedFcn = createCallbackFcn(app, @chkHideValueChanged, true);
            app.chkHide.Text = 'Hide ROIs';
            app.chkViewModel = uicheckbox(app.panEnh);
            app.chkViewModel.ValueChangedFcn = createCallbackFcn(app, @chkViewModelValueChanged, true);
            app.chkViewModel.Text = 'View Model Predictions';
            app.panROIOptions = uipanel(app.panRight);
            app.panROIOptions.Title = 'ROI Options';
            app.btnProcess = uibutton(app.panROIOptions, 'push');
            app.btnProcess.ButtonPushedFcn = createCallbackFcn(app, @btnProcessButtonPushed, true);
            app.btnProcess.Text = 'Measure';
            app.btnDrawROI = uibutton(app.panROIOptions, 'state');
            app.btnDrawROI.ValueChangedFcn = createCallbackFcn(app, @btnDrawROIValueChanged, true);
            app.btnDrawROI.Text = 'Draw';
            app.btnDelete = uibutton(app.panROIOptions, 'push');
            app.btnDelete.ButtonPushedFcn = createCallbackFcn(app, @btnDeleteButtonPushed, true);
            app.btnDelete.Text = 'Delete';
            app.btnAdjustROI = uibutton(app.panROIOptions, 'push');
            app.btnAdjustROI.ButtonPushedFcn = createCallbackFcn(app, @btnAdjustROIButtonPushed, true);
            app.btnAdjustROI.Text = 'Adjust';
            app.lblStep2 = uilabel(app.panROIOptions);
            app.lblStep2.Text = 'Step 2: Choose to Use/Edit Model Predictions for Each ROI ';
            app.btnNone = uibutton(app.panROIOptions, 'state');
            app.btnNone.ValueChangedFcn = createCallbackFcn(app, @btnNoneValueChanged, true);
            app.btnNone.Text = 'None';
            app.ddType = uidropdown(app.panROIOptions);
            app.ddType.Items = {'muscle', 'subq'};
            app.ddType.ValueChangedFcn = createCallbackFcn(app, @ddTypeValueChanged, true);
            app.ddType.Value = 'muscle';
            app.btnEditModel = uibutton(app.panROIOptions, 'state');
            app.btnEditModel.ValueChangedFcn = createCallbackFcn(app, @btnEditModelValueChanged, true);
            app.btnEditModel.Text = 'Edit Model';
            app.btnUseModel = uibutton(app.panROIOptions, 'state');
            app.btnUseModel.ValueChangedFcn = createCallbackFcn(app, @btnUseModelValueChanged, true);
            app.btnUseModel.Text = 'Use Model';
            app.panCompile = uipanel(app.panRight);
            app.panCompile.Title = 'Compile Options';
            app.btnProcessStudy = uibutton(app.panCompile, 'push');
            app.btnProcessStudy.ButtonPushedFcn = createCallbackFcn(app, @btnProcessStudyButtonPushed, true);
            app.btnProcessStudy.Text = 'Measure All ';
            app.btnCompile = uibutton(app.panCompile, 'push');
            app.btnCompile.ButtonPushedFcn = createCallbackFcn(app, @btnCompileButtonPushed, true);
            app.btnCompile.Text = 'Compile All';
            app.btnHelpProcess = uibutton(app.panCompile, 'state');
            app.btnHelpProcess.ValueChangedFcn = createCallbackFcn(app, @btnHelpProcessValueChanged, true);
            app.btnHelpProcess.Text = {'?'; ''};
            app.ddProcess = uidropdown(app.panCompile);
            app.ddProcess.Items = {'Opt 1', 'Opt 2'};
            app.ddProcess.ItemsData = {'1', '2', '3'};
            app.ddProcess.Value = '1';
            app.panModel = uipanel(app.panRight);
            app.panModel.Title = 'Model Options';
            app.ddModelApplyOpt = uidropdown(app.panModel);
            app.ddModelApplyOpt.Items = {'study', 'subject', 'image'};
            app.ddModelApplyOpt.Value = 'image';
            app.btnModelApply = uibutton(app.panModel, 'push');
            app.btnModelApply.ButtonPushedFcn = createCallbackFcn(app, @btnModelApplyButtonPushed, true);
            app.btnModelApply.Text = 'Apply';
            app.lblStep1 = uilabel(app.panModel);
            app.lblStep1.Text = 'Step 1: Apply Model';
            app.txtProcessOpts = uitextarea(app.panRight);
            app.txtProcessOpts.Editable = 'off';
            app.txtProcessOpts.Enable = 'off';
            app.txtProcessOpts.Visible = 'off';
            app.txtProcessOpts.Value = {'Option 1: All (including previously processed)'; 'Option 2: All except previously processed'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VL_CSA_AI_DICOM_to_TIF_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end